classdef testRobotPlotApp_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        MakeLoadedUR5MoveButton   matlab.ui.control.Button
        MakeLoadedPumaMoveButton  matlab.ui.control.Button
        plot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelPuma % Where I will put the loaded puma
        modelUR5 % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];
        qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
        
        modelLinearUR3 % create linear UR3
        qrLinearUR3 = [0     0     0     0     0     0     0];

        FruitSys
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
           
            mdl_puma560
            app.modelPuma = p560;
            app.modelPuma.base = transl([0,0,0]);
            % app.modelLinearUR3 = LinearUR3(transl([0,0,0.02]));
            plot(app.modelPuma,app.qrPuma,'workspace',[-2.2 1 -2 1.2 0.01 1.5]) 
            hold on;
            app.FruitSys = FruitLoopSystem();
            app.FruitSys.Environment();
            
            % Code to run once GUI is loaded successfully
            % ax = gca;
            % 
            % % Make, save and plot some robots            
            % mdl_puma560
            % app.modelPuma = p560;
            % app.modelPuma.base = transl([0.5,0,0]);
            % plot(app.modelPuma,app.qrPuma,'workspace',[-2 2 -2 2 -0.5 2])            
            % hold on;         
            % 
            % app.modelUR5 = UR5;
            % app.modelUR5.model.base = transl([-0.5,0,0]);
            % app.modelUR5.model.animate(app.qrUR5);
            % drawnow();
            % 
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            % for i = size(ax.Children,1):-1:1
            %     if strcmp(ax.Children(i).Type,'surface')
            %         delete(ax.Children(i));
            %     end
            % end
            % 
            % % Turn off this load button and enable the move button
            % app.MakeLoadedPumaMoveButton.Enable = true;
            % app.MakeLoadedUR5MoveButton.Enable = true;
            
        end

        % Button pushed function: MakeLoadedPumaMoveButton
        function MakeLoadedPumaMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrPuma,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelPuma.animate(qmatrix(robotStepIndex,:));
                drawnow;
                
            end
        end

        % Button pushed function: MakeLoadedUR5MoveButton
        function MakeLoadedUR5MoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.MakeLoadedPumaMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedPumaMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedPumaMoveButtonPushed, true);
            app.MakeLoadedPumaMoveButton.Text = 'Make Loaded Puma Move';
            app.MakeLoadedUR5MoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedUR5MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR5MoveButtonPushed, true);
            app.MakeLoadedUR5MoveButton.Text = 'Make Loaded UR5 Move';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testRobotPlotApp_V2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end